classdef ImageManipulator_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CropboxCheckBox              matlab.ui.control.CheckBox
        AlignButton                  matlab.ui.control.Button
        CCWButton                    matlab.ui.control.Button
        CWButton                     matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        framesSpinner                matlab.ui.control.Spinner
        framesSpinnerLabel           matlab.ui.control.Label
        FolderDropDown               matlab.ui.control.DropDown
        FolderDropDownLabel          matlab.ui.control.Label
        FilesDropDown                matlab.ui.control.DropDown
        FilesDropDownLabel           matlab.ui.control.Label
        CropPanel                    matlab.ui.container.Panel
        SaverectPositionsButton      matlab.ui.control.Button
        RemovefromthelistButton      matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        DimensionsEditFieldLabel     matlab.ui.control.Label
        DimensionsEditField          matlab.ui.control.EditField
        Set1endsatframeSpinnerLabel  matlab.ui.control.Label
        Set1endsatframeSpinner       matlab.ui.control.Spinner
        RandomizeCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        folder
        files
        frames
        frame
        circleMask
        markerDefaultPos
        markers
        pointROI
        frameNum
        randomFrameList
        randomFrameListIdx
        key
        rectROI
        rectPositions
        rectDefaultPos
        setSplitter
        AspectRatio
    end
    
    methods (Access = private)
        
        function  show(app)
            
            imshow(app.frame,'Parent',gca);
            if app.CropboxCheckBox.Value
                
                R=app.rectPositions.(app.files);
                %                 R(app.frameNum,1)=512-R(app.frameNum,3)/2;
                %                 R(app.frameNum,2)=512-R(app.frameNum,4)/2;
                if isequal(R(app.frameNum,[1,2]),[0,0])
                    app.Lamp.Color='red';
                else
                    app.Lamp.Color='green';
                end
                
                app.rectROI=images.roi.Rectangle(gca,'Position',R(app.frameNum,:),'AspectRatio',app.AspectRatio);
                app.DimensionsEditField.Value=sprintf('%d,%d',ceil(app.rectROI.Position(3)), ceil(app.rectROI.Position(4)));
                
                
            else
                M=app.markers.(app.files);
                app.pointROI{1} = images.roi.Point(gca,'Position',M(app.frameNum,[1,2]));
                app.pointROI{2} = images.roi.Point(gca,'Position',M(app.frameNum,[3,4]),'Color','red');
            end
            
        end
        
        function UpdateFrames(app)
            X = squeeze(dicomread(app.files));
            app.frames=squeeze(num2cell(X,[1,2]));
            
            LoadNecessaryFiles(app)
            for i=1:size(app.frames,1)
                app.frames{i}=imadjust(app.frames{i});
                app.frames{i}=app.frames{i}+app.circleMask;
            end
            
            
            app.framesSpinner.Limits=[1,size(app.frames,1)];
            app.frameNum=app.framesSpinner.Value;
            app.frame=app.frames{app.frameNum};
            if app.RandomizeCheckBox.Value
                app.randomFrameList=randperm(size(app.frames,1));
            else
                app.randomFrameList=[1:size(app.frames,1)];
            end
            app.randomFrameListIdx=0;
            app.Set1endsatframeSpinner.Value=app.setSplitter;
            if app.CropboxCheckBox.Value
                if ~isfield(app.rectPositions,app.files)
                    app.rectPositions.( app.files)=repmat(app.rectDefaultPos,size(app.frames,1),1);
                end
                
                R=getfield(app.rectPositions,app.files);
                if size(R,1)<size(app.frames,1)
                    app.rectPositions.( app.files)=repmat(app.rectDefaultPos,size(app.frames,1),1);
                end
                
                
            else
                
                if ~isfield(app.markers,app.files)
                    app.markers.( app.files)=repmat(app.markerDefaultPos,size(app.frames,1),1);
                end
                
                M=getfield(app.markers,app.files);
                if size(M,1)<size(app.frames,1)
                    app.markers.( app.files)=repmat(app.markerDefaultPos,size(app.frames,1),1);
                end
            end
        end
        
        function UpdatePostion(app)
            if app.CropboxCheckBox.Value
                app.rectROI.Position(4)=app.rectROI.Position(3)*app.AspectRatio;
                app.rectPositions.(app.files)(app.frameNum,:)=app.rectROI.Position;
                
                if app.frameNum<=app.setSplitter
                    set=[1:app.setSplitter];
                else
                    set=[app.setSplitter+1:size(app.frames,1)];
                end
                app.rectPositions.(app.files)(set,3)=app.rectROI.Position(3);
                app.rectPositions.(app.files)(set,4)=app.rectROI.Position(4);
                
            else
                
                M=getfield(app.markers,app.files);
                
                for i=1:size(app.pointROI,2)
                    M(app.frameNum, [2*i-1,2*i])=app.pointROI{i}.Position;
                end
                
                app.markers.(app.files)=M;
            end
        end
        
        function LoadNecessaryFiles(app)
            if exist("markers.mat",'file')
                load markers.mat
                app.markers=markers;
            else
                app.markers.(app.files)=repmat(app.markerDefaultPos,size(app.frames,1),1);
                markers=app.markers;
                save markers.mat markers
            end
            
            if exist("rectPositions.mat",'file')
                load rectPositions.mat
                app.rectPositions=rectPositions;
                if ~exist ("setSplitter",'var')
                    setSplitter=size(app.frames,1);
                end
                app.setSplitter=setSplitter;
            else
                app.rectPositions.(app.files)=repmat(app.rectDefaultPos,size(app.frames,1),1);
                rectPositions=app.rectPositions;
                
                setSplitter=size(app.frames,1);
                app.setSplitter=setSplitter;
                save rectPositions.mat rectPositions setSplitter
            end
            
            
            if exist("circleMask.mat",'file')
                load circleMask.mat
            else
                circleMask=uint16(zeros(1024));
                roi_circle = images.roi.Circle('Center',[512 512],'Radius',495);
                y=[1:1024];
                for i=1:1024
                    x=i*ones(1,1024);
                    circleMask(i,:)=1-inROI(roi_circle,x,y);
                end
                circleMask=circleMask*100000;
                save ('circleMask', 'circleMask');
            end
            
            app.circleMask=circleMask;
            
        end
        
        function PermuteRandomly(app)
            
            switch app.key
                case '2'
                    app.randomFrameListIdx=app.randomFrameListIdx+1;
                    
                case '1'
                    app.randomFrameListIdx=app.randomFrameListIdx-1;
                    
            end
            app.randomFrameListIdx
            if app.randomFrameListIdx<1
                app.randomFrameListIdx=1;
            elseif app.randomFrameListIdx>size(app.frames,1)     %% Check this%%
                app.randomFrameListIdx=size(app.frames,1);
            end
            
            switch app.key
                case {'1','2'}
                    app.framesSpinner.Value=app.randomFrameList(app.randomFrameListIdx);
                    UpdatePostion(app)
                    app.frameNum=app.framesSpinner.Value;
                    app.frame=app.frames{app.framesSpinner.Value};
                    show(app)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            figure('KeyPressFcn', @KeyFcn); % new traditional figure with interactive features
            function KeyFcn(~, event)
                app.key=event.Key;
                PermuteRandomly(app)
            end
            
            
            app.markerDefaultPos=[512,512,512,400,512,300,512,200,512,100,512,600];
            app.rectDefaultPos=[512-300,512-400,600,800];
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            dinfo = dir();
            dinfo(ismember( {dinfo.name}, {'.', '..','Index.xlsx'})) = [];
            
            %Read all the folders in dir and update
            for i=1:size(dinfo,1)
                app.FolderDropDown.Items{i}=dinfo(i).name;
            end
            app.folder=app.FolderDropDown.Value;
            
            %Update dir
            cd(app.FolderDropDown.Value)
            
            %Read all the files in the folders and update
            files = dir(fullfile(cd, '*.DCM'));
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            app.AspectRatio=1.25;
            
            UpdateFrames(app)
            show(app)


        end

        % Value changed function: FolderDropDown
        function FolderDropDownValueChanged(app, event)
            %UpdatePostion(app)
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            
            cd(app.FolderDropDown.Value)
            app.folder=app.FolderDropDown.Value;
            
            files = dir(fullfile(cd, '*.DCM'));
            app.FilesDropDown.Items={};
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            
            UpdateFrames(app)


            show(app)
            
            
        end

        % Value changed function: FilesDropDown
        function FilesDropDownValueChanged(app, event)
            % UpdatePostion(app)
            app.files=app.FilesDropDown.Value;
            UpdateFrames(app)
            
            show(app)
            
        end

        % Value changed function: framesSpinner
        function framesSpinnerValueChanged(app, event)
            UpdatePostion(app)
            app.frameNum=app.framesSpinner.Value;
            app.frame=app.frames{app.framesSpinner.Value};
            show(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            UpdatePostion(app)
            markers=app.markers;
            save markers.mat markers
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            app.frame=imrotate(app.frame,90);
            show(app)
            for i=1:size(app.frames,1)
                app.frames{i}=imrotate(app.frames{i},90);
            end
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            app.frame=imrotate(app.frame,-90);
            show(app)
            for i=1:size(app.frames,1)
                app.frames{i}=imrotate(app.frames{i},-90);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            app.key=event.Key;
            PermuteRandomly(app)
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed2(app, event)
            
            M=getfield(app.markers,app.files);
            totalFrames=size(app.frames,1);
            if app.folder=='1'
                for i=1:size(M,2)
                    M(:,i)=smooth(M(:,i));
                end
            end
            
            Tform=FindTform2( M(1:totalFrames,[1:4]));
            
            for i=1:totalFrames
                app.frames{i}=imwarp(app.frames{i},Tform(i),'OutputView',...
                    imref2d(size(app.frames{i})),'FillValues', 10e6);
            end
            app.frame=app.frames{app.frameNum};
            show(app)
        end

        % Value changed function: CropboxCheckBox
        function CropboxCheckBoxValueChanged(app, event)
            if app.CropboxCheckBox.Value
                app.CropPanel.Enable='on';
                
            else
                app.CropPanel.Enable='off';
            end
            UpdateFrames(app)
            show(app)
        end

        % Button pushed function: RemovefromthelistButton
        function RemovefromthelistButtonPushed(app, event)
            app.rectROI.Position(1)=0;
            app.rectROI.Position(2)=0;
            app.Lamp.Color='red';
            
        end

        % Button pushed function: SaverectPositionsButton
        function SaverectPositionsButtonPushed(app, event)
            UpdatePostion(app)
            rectPositions=app.rectPositions;
            setSplitter=app.setSplitter;
            save rectPositions.mat rectPositions setSplitter
        end

        % Value changed function: Set1endsatframeSpinner
        function Set1endsatframeSpinnerValueChanged(app, event)
            app.setSplitter = app.Set1endsatframeSpinner.Value;
        end

        % Value changed function: RandomizeCheckBox
        function RandomizeCheckBoxValueChanged(app, event)
            UpdateFrames(app)
            show(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.CropboxCheckBox = uicheckbox(app.UIFigure);
            app.CropboxCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropboxCheckBoxValueChanged, true);
            app.CropboxCheckBox.Text = 'Crop box';
            app.AlignButton = uibutton(app.UIFigure, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed2, true);
            app.AlignButton.Text = 'Align';
            app.CCWButton = uibutton(app.UIFigure, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Text = '90 CCW';
            app.CWButton = uibutton(app.UIFigure, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Text = '90 CW';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.framesSpinner = uispinner(app.UIFigure);
            app.framesSpinner.Limits = [1 100];
            app.framesSpinner.ValueChangedFcn = createCallbackFcn(app, @framesSpinnerValueChanged, true);
            app.framesSpinner.Value = 1;
            app.framesSpinnerLabel = uilabel(app.UIFigure);
            app.framesSpinnerLabel.Text = 'frames';
            app.FolderDropDown = uidropdown(app.UIFigure);
            app.FolderDropDown.Items = {};
            app.FolderDropDown.ValueChangedFcn = createCallbackFcn(app, @FolderDropDownValueChanged, true);
            app.FolderDropDown.Value = {};
            app.FolderDropDownLabel = uilabel(app.UIFigure);
            app.FolderDropDownLabel.Text = 'Folder';
            app.FilesDropDown = uidropdown(app.UIFigure);
            app.FilesDropDown.Items = {};
            app.FilesDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesDropDownValueChanged, true);
            app.FilesDropDown.Value = {};
            app.FilesDropDownLabel = uilabel(app.UIFigure);
            app.FilesDropDownLabel.Text = 'Files';
            app.CropPanel = uipanel(app.UIFigure);
            app.CropPanel.Enable = 'off';
            app.CropPanel.Title = 'Crop';
            app.SaverectPositionsButton = uibutton(app.CropPanel, 'push');
            app.SaverectPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaverectPositionsButtonPushed, true);
            app.SaverectPositionsButton.Text = 'Save rectPositions';
            app.RemovefromthelistButton = uibutton(app.CropPanel, 'push');
            app.RemovefromthelistButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefromthelistButtonPushed, true);
            app.RemovefromthelistButton.Text = 'Remove from the list';
            app.Lamp = uilamp(app.CropPanel);
            app.DimensionsEditFieldLabel = uilabel(app.CropPanel);
            app.DimensionsEditFieldLabel.Text = 'Dimensions';
            app.DimensionsEditField = uieditfield(app.CropPanel, 'text');
            app.Set1endsatframeSpinnerLabel = uilabel(app.CropPanel);
            app.Set1endsatframeSpinnerLabel.Text = 'Set 1 ends at frame';
            app.Set1endsatframeSpinner = uispinner(app.CropPanel);
            app.Set1endsatframeSpinner.ValueChangedFcn = createCallbackFcn(app, @Set1endsatframeSpinnerValueChanged, true);
            app.RandomizeCheckBox = uicheckbox(app.UIFigure);
            app.RandomizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizeCheckBoxValueChanged, true);
            app.RandomizeCheckBox.Text = 'Randomize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageManipulator_App2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end