classdef PCA_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FemurCheckBox            matlab.ui.control.CheckBox
        TibiaCheckBox            matlab.ui.control.CheckBox
        PatellaCheckBox          matlab.ui.control.CheckBox
        ACLCheckBox              matlab.ui.control.CheckBox
        PCLCheckBox              matlab.ui.control.CheckBox
        MCLCheckBox              matlab.ui.control.CheckBox
        LCLCheckBox              matlab.ui.control.CheckBox
        PLCheckBox               matlab.ui.control.CheckBox
        PC1SliderLabel           matlab.ui.control.Label
        PC1Slider                matlab.ui.control.Slider
        PC2SliderLabel           matlab.ui.control.Label
        PC2Slider                matlab.ui.control.Slider
        PC3SliderLabel           matlab.ui.control.Label
        PC3Slider                matlab.ui.control.Slider
        PC4SliderLabel           matlab.ui.control.Label
        PC4Slider                matlab.ui.control.Slider
        PC5SliderLabel           matlab.ui.control.Label
        PC5Slider                matlab.ui.control.Slider
        PC6SliderLabel           matlab.ui.control.Label
        PC6Slider                matlab.ui.control.Slider
        PC7SliderLabel           matlab.ui.control.Label
        PC7Slider                matlab.ui.control.Slider
        GeneralSliderLabel       matlab.ui.control.Label
        GeneralSlider            matlab.ui.control.Slider
        PCSpinnerLabel           matlab.ui.control.Label
        PCSpinner                matlab.ui.control.Spinner
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        Type_numberSpinnerLabel  matlab.ui.control.Label
        Type_numberSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        instance
        loadings
        triangulation
        Ipts
        mu_pcs
        sigma_pcs
        PCrowvectorsReduced
        MU
    end
    
    methods (Access = private)
        
        function Update_PCspinners(app)
            vect=app.instance(:,app.Type_numberSpinner.Value);
            app.PC1Slider.Value=vect(1);
            app.PC2Slider.Value=vect(2);
            app.PC3Slider.Value=vect(3);
            app.PC4Slider.Value=vect(4);
            app.PC5Slider.Value=vect(5);
            app.PC6Slider.Value=vect(6);
            app.PC7Slider.Value=vect(7);
            app.GeneralSlider.Value=vect(8);
            app.PCSpinner.Value=8;
            app.loadings=vect;
            show(app)
        end
        
        function  show(app)
            ax=gca;
            camPosition=campos(ax);
            % Generate PC scores for specific instance
            NewPCs=app.mu_pcs' + app.loadings.*app.sigma_pcs';
            
            % Multiply from PC space to Cartesian space
            NewData=app.PCrowvectorsReduced'*NewPCs;
            
            % Realign data with respect to the mean specimen
            X=NewData+app.MU';
            
            % Create data structure of the new instance
            [S]=BuildModel_OS(X,1);
           
            [~,fem,tib,pat,acl,pcl,mcl,lcl,pl]=GetKneeCoordinates(app.loadings);
            
           cla(ax);
            hold(ax,'on');
            if app.FemurCheckBox.Value
                %fem=S.bone.fem.coord.model(:,2:end);
                % trimesh(app.triangulation.fem,fem(:,1),fem(:,2),fem(:,3),'Parent',app.UIAxes);
                trisurf(app.triangulation.fem,fem(:,1),fem(:,2),fem(:,3),'Parent',ax);
                
            end
            %hold(ax,'on');
            if app.TibiaCheckBox.Value
               % tib=S.bone.tib.coord.model(:,2:end);
                trisurf(app.triangulation.tib,tib(:,1),tib(:,2),tib(:,3),'Parent',ax);
            end
            
            if app.PatellaCheckBox.Value
                %pat=S.bone.pat.coord.model(:,2:end);
                trisurf(app.triangulation.pat,pat(:,1),pat(:,2),pat(:,3),'Parent',ax);
            end
            
            if app.ACLCheckBox.Value
                %acl=S.ligament.ACL.nodes.spg(:,2:end);
                plot3(acl(:,1),acl(:,2),acl(:,3),'go-','LineWidth',2,'Parent',ax);
            end
            
            if app.PCLCheckBox.Value
                %pcl=S.ligament.PCL.nodes.spg(:,2:end);
                plot3(pcl(:,1),pcl(:,2),pcl(:,3),'bo-','LineWidth',2,'Parent',ax);
            end
            
            if app.MCLCheckBox.Value
               % mcl=S.ligament.MCL.nodes.mem(:,2:end);
                plot3(mcl(:,1),mcl(:,2),mcl(:,3),'ro-','LineWidth',2,'Parent',ax);
            end
            
            if app.LCLCheckBox.Value
               % lcl=S.ligament.LCL.nodes.mem(:,2:end);
                plot3(lcl(:,1),lcl(:,2),lcl(:,3),'go-','LineWidth',2,'Parent',ax);
            end
            
            if app.PLCheckBox.Value
               % pl=S.ligament.PL.nodes.mem(:,2:end);
                plot3(pl(:,1),pl(:,2),pl(:,3),'bo-','LineWidth',2,'Parent',ax);
            end
            
            
            hold(ax,'off');
            axis(ax,'equal')
            grid(ax,'on');
            campos(ax, camPosition);
            campos(ax,'manual')
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run parameters.m
            figure
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Matlab\DU_SSM'
            
            a=load('triangulation');
            load('Insertion_points');
            app.Ipts=Ipts;
            app.triangulation=a.triangulation;


            result='TRAINING_SET_INSTANCES.dat';
            app.instance=dlmread(result);
            Ns=size(app.instance,2); % Number of instances
            
            % Results from rrincipal component analysis
            app.MU=dlmread('PC_MU.dat');
            COEFF=dlmread('PC_COEFFS.dat');
            PC_DATA=dlmread('PC_DATA.dat');  %contains means and std devs from the training set
            Npc=size(app.instance,1); % Number of PC scores
            %fprintf(['Building all input files with the first ',num2str(Npc),' PC scores\n'])
            
            % means and std deviations for each PC
            app.mu_pcs=PC_DATA(1,:);
            app.sigma_pcs=PC_DATA(2,:);
            
            %  Reduce row vector to the number of PCs to be included
            for pc=1:Npc
                app.PCrowvectorsReduced(pc,:)=COEFF(pc,:);
            end


            Update_PCspinners(app)
            
            
        end

        % Value changed function: PC1Slider
        function PC1SliderValueChanged(app, event)
            app.loadings(1)=app.PC1Slider.Value;
            show(app);
        end

        % Value changed function: PC2Slider
        function PC2SliderValueChanged(app, event)
            app.loadings(2)=app.PC2Slider.Value;
            show(app);
        end

        % Value changed function: PC3Slider
        function PC3SliderValueChanged(app, event)
            app.loadings(3)=app.PC3Slider.Value;
            show(app);
        end

        % Value changed function: PC4Slider
        function PC4SliderValueChanged(app, event)
            app.loadings(4)=app.PC4Slider.Value;
            show(app);
        end

        % Value changed function: PC5Slider
        function PC5SliderValueChanged(app, event)
            app.loadings(5)=app.PC5Slider.Value;
            show(app);
        end

        % Value changed function: PC6Slider
        function PC6SliderValueChanged(app, event)
            app.loadings(6)=app.PC6Slider.Value;
            show(app);
        end

        % Value changed function: PC7Slider
        function PC7SliderValueChanged(app, event)
            app.loadings(7)=app.PC7Slider.Value;
            show(app);
        end

        % Value changed function: GeneralSlider
        function GeneralSliderValueChanged(app, event)
            app.loadings(app.PCSpinner.Value)=app.GeneralSlider.Value;
            show(app);
        end

        % Value changed function: PCSpinner
        function PCSpinnerValueChanged(app, event)
            app.GeneralSlider.Value=app.loadings(app.PCSpinner.Value);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.instance=dlmread(value);
            Ns=size(app.instance,2); % Number of instances
            app.Type_numberSpinner.Limits=[1,Ns];
            app.Type_numberSpinner.Value=1;
            Update_PCspinners(app)
        end

        % Value changed function: Type_numberSpinner
        function Type_numberSpinnerValueChanged(app, event)
            Update_PCspinners(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FemurCheckBox = uicheckbox(app.UIFigure);
            app.FemurCheckBox.Text = 'Femur';
            app.FemurCheckBox.Value = true;
            app.TibiaCheckBox = uicheckbox(app.UIFigure);
            app.TibiaCheckBox.Text = 'Tibia';
            app.PatellaCheckBox = uicheckbox(app.UIFigure);
            app.PatellaCheckBox.Text = 'Patella';
            app.ACLCheckBox = uicheckbox(app.UIFigure);
            app.ACLCheckBox.Text = 'ACL';
            app.PCLCheckBox = uicheckbox(app.UIFigure);
            app.PCLCheckBox.Text = 'PCL';
            app.MCLCheckBox = uicheckbox(app.UIFigure);
            app.MCLCheckBox.Text = 'MCL';
            app.LCLCheckBox = uicheckbox(app.UIFigure);
            app.LCLCheckBox.Text = 'LCL';
            app.PLCheckBox = uicheckbox(app.UIFigure);
            app.PLCheckBox.Text = 'PL';
            app.PC1SliderLabel = uilabel(app.UIFigure);
            app.PC1SliderLabel.Text = 'PC1';
            app.PC1Slider = uislider(app.UIFigure);
            app.PC1Slider.Limits = [-5 5];
            app.PC1Slider.ValueChangedFcn = createCallbackFcn(app, @PC1SliderValueChanged, true);
            app.PC2SliderLabel = uilabel(app.UIFigure);
            app.PC2SliderLabel.Text = 'PC2';
            app.PC2Slider = uislider(app.UIFigure);
            app.PC2Slider.Limits = [-5 5];
            app.PC2Slider.ValueChangedFcn = createCallbackFcn(app, @PC2SliderValueChanged, true);
            app.PC3SliderLabel = uilabel(app.UIFigure);
            app.PC3SliderLabel.Text = 'PC3';
            app.PC3Slider = uislider(app.UIFigure);
            app.PC3Slider.Limits = [-5 5];
            app.PC3Slider.ValueChangedFcn = createCallbackFcn(app, @PC3SliderValueChanged, true);
            app.PC4SliderLabel = uilabel(app.UIFigure);
            app.PC4SliderLabel.Text = 'PC4';
            app.PC4Slider = uislider(app.UIFigure);
            app.PC4Slider.Limits = [-5 5];
            app.PC4Slider.ValueChangedFcn = createCallbackFcn(app, @PC4SliderValueChanged, true);
            app.PC5SliderLabel = uilabel(app.UIFigure);
            app.PC5SliderLabel.Text = 'PC5';
            app.PC5Slider = uislider(app.UIFigure);
            app.PC5Slider.Limits = [-5 5];
            app.PC5Slider.ValueChangedFcn = createCallbackFcn(app, @PC5SliderValueChanged, true);
            app.PC6SliderLabel = uilabel(app.UIFigure);
            app.PC6SliderLabel.Text = 'PC6';
            app.PC6Slider = uislider(app.UIFigure);
            app.PC6Slider.Limits = [-5 5];
            app.PC6Slider.ValueChangedFcn = createCallbackFcn(app, @PC6SliderValueChanged, true);
            app.PC7SliderLabel = uilabel(app.UIFigure);
            app.PC7SliderLabel.Text = 'PC7';
            app.PC7Slider = uislider(app.UIFigure);
            app.PC7Slider.Limits = [-5 5];
            app.PC7Slider.ValueChangedFcn = createCallbackFcn(app, @PC7SliderValueChanged, true);
            app.GeneralSliderLabel = uilabel(app.UIFigure);
            app.GeneralSliderLabel.Text = 'General';
            app.GeneralSlider = uislider(app.UIFigure);
            app.GeneralSlider.Limits = [-5 5];
            app.GeneralSlider.ValueChangedFcn = createCallbackFcn(app, @GeneralSliderValueChanged, true);
            app.PCSpinnerLabel = uilabel(app.UIFigure);
            app.PCSpinnerLabel.Text = 'PC';
            app.PCSpinner = uispinner(app.UIFigure);
            app.PCSpinner.Limits = [8 49];
            app.PCSpinner.ValueChangedFcn = createCallbackFcn(app, @PCSpinnerValueChanged, true);
            app.PCSpinner.Value = 8;
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'TRAINING_SET_INSTANCES.dat', 'MODE_INSTANCES.dat', 'NEW_INSTANCES.dat'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'TRAINING_SET_INSTANCES.dat';
            app.Type_numberSpinnerLabel = uilabel(app.UIFigure);
            app.Type_numberSpinnerLabel.Text = 'Type_number';
            app.Type_numberSpinner = uispinner(app.UIFigure);
            app.Type_numberSpinner.Limits = [1 Inf];
            app.Type_numberSpinner.ValueChangedFcn = createCallbackFcn(app, @Type_numberSpinnerValueChanged, true);
            app.Type_numberSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end