classdef ReducetoEdges_Synthetic_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        framesSpinnerLabel         matlab.ui.control.Label
        framesSpinner              matlab.ui.control.Spinner
        ImageCheckBox              matlab.ui.control.CheckBox
        ProcessingstatusLampLabel  matlab.ui.control.Label
        ProcessingstatusLamp       matlab.ui.control.Lamp
        Multilayer_CannyPanel      matlab.ui.container.Panel
        MultilayerCannyCheckBox    matlab.ui.control.CheckBox
        StartlevelSpinnerLabel     matlab.ui.control.Label
        StartlevelSpinner          matlab.ui.control.Spinner
        EndlevelSpinnerLabel       matlab.ui.control.Label
        EndlevelSpinner            matlab.ui.control.Spinner
        WeightratioSpinnerLabel    matlab.ui.control.Label
        WeightratioSpinner         matlab.ui.control.Spinner
        SaveButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        filter;
        frames
        frame
        Canny   % Canny parameters
        Sobel   % Sobel parameters
        GradientI%Gradient parameters
        circleMask
        folder
        files
        poses
        markers
        frameNum
        key
        rectROI
        rectPositions
        Tform
        multiLayerCannyArgs
        tiledPoses
        tiledcirleMasks
        posesPerImage
        posesInaTile
        borderMask
        MLC
    end
    
    methods (Access = private)
        
        function [tiledImage]=TileImages(app,rawImages)
            rows=sqrt(app.posesPerImage);
            cols=rows;
            r=256;  %TileSize
            tileIndices=reshape(1:app.posesPerImage,rows,cols);
            tileIndices=tileIndices';
            
            rowImages=[];
            tiledImage=[];
            frameNumberMatrix=[];
            frame=im2uint16(zeros(r,r));
            
            for row=1:rows
                for col=1:cols
                    tileIndex=tileIndices(row,col)
                    frames=rawImages{tileIndex};
                    poseIndex=min(app.SetsSpinner.Value,size(frames,3));
                    
                    
                    if isempty(app.posesInaTile{tileIndex})
                        frameNumberMatrix=[frameNumberMatrix,0];
                        frame=frame*0;
                    else
                        frameNumberMatrix=[frameNumberMatrix,app.posesInaTile{tileIndex}(poseIndex)];
                        frame=frames(:,:,poseIndex);
                    end


                    rowImages=[rowImages,frame];
                end
                tiledImage=[tiledImage;rowImages];
                rowImages=[];
                
            end
            frameNumberMatrix=reshape(frameNumberMatrix,rows,cols)'
        end
        
        
        function  show(app)
            
            app.ProcessingstatusLamp.Color='red';
            
            
            figure(1);
            ax=gca;
            
            
            if app.TilesCheckBox.Value
                frame=TileImages(app,app.tiledPoses);
                circleMask=TileImages(app,app.tiledcirleMasks);
                
                
                circleMask=circleMask+app.borderMask;
                
            else
                
                
                frame=imwarp(app.frame,app.Tform(app.frameNum),'OutputView',...
                    imref2d(size(app.frame)),'FillValues', 0);
                circleMask=imwarp(app.circleMask,app.Tform(app.frameNum),'OutputView',...
                    imref2d(size(app.circleMask)),'FillValues', 0);
                
                if app.CropboxCheckBox.Value
                    frame=imcrop(frame,app.rectPositions.(app.files)(app.frameNum,:));
                    frame=imresize(frame,[256,256]);
                    circleMask=imcrop(circleMask,app.rectPositions.(app.files)(app.frameNum,:));
                    circleMask=imresize(circleMask,[256,256]);
                end
                
            end
            R_Canny=uint8(zeros(size(frame,1),size(frame,2),3));
            B_Sobel=R_Canny;
            G_GradientI=R_Canny;
            
            if app.MultilayerCannyCheckBox.Value
                Edgeimage=MultiLayerCanny(frame,app.MLC);
                
                Edgeimage=im2uint8(Edgeimage);
            else
                if strcmp(app.filter,'Canny') ||app.CannyCheckBox.Value || app.GradientCannyCheckBox.Value
                    BW_Canny=ReduceToEdges5(frame,app.Canny,0);
                    R_Canny(:,:,1)=uint8(BW_Canny)*255;%RGB_Canny(:,:,1);      %Retain only Red
                end
                
                if strcmp(app.filter,'GradientI') || app.GradientICheckBox.Value ||app.GradientCannyCheckBox.Value
                    gradImage=ReduceToEdges5(frame,app.GradientI,0);
                    B=imcomplement(circleMask>0);
                    gradImage=mat2gray(gradImage.*B);
                    G_GradientI(:,:,2)=(gradImage*255);
                end
                
                if app.GradientCannyCheckBox.Value
                    Edgeimage=SubtractGradientCanny(R_Canny(:,:,1),G_GradientI(:,:,2),circleMask);
                else
                    Edgeimage=R_Canny+B_Sobel+G_GradientI;
                    
                end
            end
            Edgeimage=Edgeimage-(im2uint8(circleMask));
            
            if app.OnlynoiseCheckBox.Value
                BW=Edgeimage>0;
                BW=BW-bwareaopen(BW,app.PSpinner_2.Value);
                Edgeimage=Edgeimage.*(im2uint8(BW)/255);
            end
            
            
            if app.ImageCheckBox.Value
                imshowpair(Edgeimage,(frame),'blend','Parent',ax);
                
            else
                % imshow(Edgeimage,'Parent',app.UIAxes);
                Edgeimage=imresize(Edgeimage,1/app.LevelSpinner.Value);
                imshow(Edgeimage,'Parent',ax);
            end


            app.ProcessingstatusLamp.Color='green';
        end
        
        function UpdateFrames(app)
            X = squeeze(dicomread(app.files));
            app.frames=squeeze(num2cell(X,[1,2]));
            app.folder=str2num(app.FolderDropDown.Value);
            
            LoadNecessaryFiles(app)
            %             for i=1:size(app.frames,1)
            %                 app.frames{i}=im2uint8(medfilt2(app.frames{i}));
            %                 %app.frames{i}=app.frames{i}+app.circleMask;
            %             end
            
            app.framesSpinner.Limits=[1,size(app.frames,1)];
            app.frameNum=app.framesSpinner.Value;
            
            app.frame=app.frames{app.frameNum};
            
            app.filter='GradientI';
            app.PSpinner.Value=app.(app.filter).P;
            app.threshSpinner.Value=app.(app.filter).thresh;
            app.LevelSpinner.Value=round(app.(app.filter).sigma2);
            
            [app.posesInaTile,N]=FindPosesInaTile(app.folder);


        end
        
        function LoadNecessaryFiles(app)


            if exist("markers.mat",'file')
                load markers.mat
                
                if ~isfield(markers,app.files)
                    markerDefaultPos=[512,512,512,400,512,300,512,200,512,100,512,600];
                    markers.(app.files)= repmat(markerDefaultPos,size(app.frames,1),1);
                end
                
                M=markers.(app.files);
                totalFrames=size(app.frames,1);
                if app.folder==1
                    for i=1:size(M,2)
                        M(:,i)=smooth(M(:,i));
                    end
                end
                app.Tform=FindTform2( M(1:totalFrames,[1:4]));
            end
            
            if exist("rectPositions.mat",'file')
                load rectPositions.mat
                app.rectPositions=rectPositions;
            end
            
            
            if exist("circleMask.mat",'file')
                load circleMask.mat
                app.circleMask=circleMask;
            end
            
            if exist("processedImages.mat",'file')
                load processedImages.mat
                app.tiledPoses=tiledPoses;
                app.tiledcirleMasks=tiledcirleMasks;
            end
            
            load edgesParams2.mat
            C=Canny;
            S=Sobel;
            if ~exist('GradienI')
                GradienI=Canny;
                GradienI.name='GradientI';
            end
            G=GradienI;
            clear Canny Sobel GradienI
            
            if exist("edgesParams3.mat",'file')
                load edgesParams3.mat
            else
                
                Canny.Rotation=C;
                Sobel.Rotation=S;
                GradientI.Rotation=G;
                save ('edgesParams3', 'Canny','Sobel','GradientI');
            end
            
            if ~isfield(Canny,app.files)
                C.thresh=0.001;
                G.P=100;
                G.thresh=0.1;
                
                Canny.(app.files)=C;
                Sobel.(app.files)=S;
                GradientI.(app.files)=G;
                
            end
            
            app.Canny=Canny.(app.files);
            app.Sobel=Sobel.(app.files);
            app.GradientI=GradientI.(app.files);
            
            if exist("multiLayerCannyparams.mat",'file')
                load multiLayerCannyparams.mat
            else
                MLC.start=1;
                MLC.end=6;
                MLC.weight=3;
                MLC.P=300;
                save ('multiLayerCannyparams', 'MLC');
            end
            
            app.MLC=MLC;
            app.StartlevelSpinner.Value=app.MLC.start;
            app.EndlevelSpinner.Value=app.MLC.end;
            app.WeightratioSpinner.Value=app.MLC.weight;
            app.PSpinner_2.Value=MLC.P;


        end
        
        function [borderMask]=CreateAllBorders(app)
            rows=sqrt(app.posesPerImage);
            cols=rows;
            r=256;  %TileSize
            
            borderMask=zeros(r*rows, r*cols);
            
            
            stripes=0:r:r*rows;
            stripes(1)=1;
            
            borderMask(stripes,:)=1; %Somehigh number
            borderMask(:,stripes)=1; %Somehigh number
            
            borderMask=borderMask>0;
            SE = strel('square', 15);
            borderMask=imdilate(borderMask,SE);
            
            
            borderMask=im2uint16(borderMask);
            
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(1);
            
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            dinfo = dir();
            dinfo(ismember( {dinfo.name}, {'.', '..','Index.xlsx'})) = [];
            
            %Read all the folders in dir and update
            for i=1:size(dinfo,1)
                app.FolderDropDown.Items{i}=dinfo(i).name;
            end
            
            
            %Update dir
            cd(app.FolderDropDown.Value)
            
            %Read all the files in the folders and update
            files = dir(fullfile(cd, '*.DCM'));
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            %             app.multiLayerCannyArgs=[1,7,3];
            %             app.StartlevelSpinner.Value=app.multiLayerCannyArgs(1);
            %             app.EndlevelSpinner.Value=app.multiLayerCannyArgs(2);
            %             app.WeightratioSpinner.Value=app.multiLayerCannyArgs(3);
            app.poses = readtable('Poses');
            app.posesPerImage=16;
            
            
            app.borderMask=CreateAllBorders(app);
            
            UpdateFrames(app)
            
            show(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MLC=app.MLC;
            save ('multiLayerCannyparams', 'MLC');
            
            NoiseCollection=[];
            if exist("NoiseCollection.mat",'file')
                load NoiseCollection.mat
            end
            
            
            frameCount=1;
            for tile=1:size(app.tiledPoses,2)
                
                if ~isempty(app.tiledPoses{tile})
                    for pose =1:size(app.tiledPoses{tile},3)
                        frame=app.tiledPoses{tile}(:,:,pose);
                        circleMask=app.tiledcirleMasks{tile}(:,:,pose);
                        
                        Edgeimage=MultiLayerCanny(frame,app.MLC);
                        Edgeimage=im2uint8(Edgeimage);
                        Edgeimage=Edgeimage-(im2uint8(circleMask));
                        
                        %Collect Noise
                        BW=Edgeimage>0;
                        BW=BW-bwareaopen(BW,app.PSpinner_2.Value);
                        %Edgeimage=Edgeimage.*im2uint8(BW);
                        noiseImage=Edgeimage.*(im2uint8(BW)/255);
                        
                        NoiseCollection{app.folder}(frameCount,:,:)=noiseImage;
                        frameCount=frameCount+1;
                                                
                    end
                end
            end
            
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            save ('NoiseCollection', 'NoiseCollection');
            
            cd(app.FolderDropDown.Value);


        end

        % Callback function
        function FilterDropDownValueChanged(app, event)
            app.filter=app.FilterDropDown.Value;
            app.PSpinner.Value=app.(app.filter).P;
            app.threshSpinner.Value=app.(app.filter).thresh;
            app.LevelSpinner.Value=round(app.(app.filter).sigma2);
            
            show(app)
        end

        % Value changed function: framesSpinner
        function framesSpinnerValueChanged(app, event)
            app.frameNum = app.framesSpinner.Value;
            app.frame=app.frames{app.frameNum};
            show(app)
        end

        % Value changed function: ImageCheckBox
        function ImageCheckBoxValueChanged(app, event)
            show(app);
        end

        % Callback function
        function threshSpinnerValueChanged(app, event)
            app.(app.filter).thresh = app.threshSpinner.Value;
            show(app)
        end

        % Callback function
        function StepSliderValueChanged(app, event)
            app.threshSpinner.Step=10^(app.StepSlider.Value);
        end

        % Callback function
        function LevelSpinnerValueChanged(app, event)
            app.(app.filter).sigma2 = app.LevelSpinner.Value;
            show(app)
        end

        % Callback function
        function PSpinnerValueChanged(app, event)
            app.(app.filter).P = ceil(app.PSpinner.Value);
            show(app);
        end

        % Callback function
        function GradientCannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab.Title
        end

        % Callback function
        function CannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Callback function
        function GradientICheckBoxValueChanged(app, event)
            show(app);
        end

        % Callback function
        function FolderDropDownValueChanged(app, event)
            %UpdatePostion(app)
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            
            cd(app.FolderDropDown.Value)
            
            files = dir(fullfile(cd, '*.DCM'));
            app.FilesDropDown.Items={};
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            
            UpdateFrames(app)
            
            show(app)
        end

        % Callback function
        function FilesDropDownValueChanged(app, event)
            app.files=app.FilesDropDown.Value;
            UpdateFrames(app);
            show(app);
        end

        % Callback function
        function CropboxCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: MultilayerCannyCheckBox
        function MultilayerCannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: StartlevelSpinner
        function StartlevelSpinnerValueChanged(app, event)
            app.MLC.start = app.StartlevelSpinner.Value;
            show(app);
        end

        % Value changed function: EndlevelSpinner
        function EndlevelSpinnerValueChanged(app, event)
            app.MLC.end= app.EndlevelSpinner.Value;
            show(app);
        end

        % Value changed function: WeightratioSpinner
        function WeightratioSpinnerValueChanged(app, event)
            app.MLC.weight = app.WeightratioSpinner.Value;
            show(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.framesSpinnerLabel = uilabel(app.UIFigure);
            app.framesSpinnerLabel.Text = 'frames';
            app.framesSpinner = uispinner(app.UIFigure);
            app.framesSpinner.Limits = [1 100];
            app.framesSpinner.ValueChangedFcn = createCallbackFcn(app, @framesSpinnerValueChanged, true);
            app.framesSpinner.Value = 1;
            app.ImageCheckBox = uicheckbox(app.UIFigure);
            app.ImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageCheckBoxValueChanged, true);
            app.ImageCheckBox.Text = 'Image';
            app.ProcessingstatusLampLabel = uilabel(app.UIFigure);
            app.ProcessingstatusLampLabel.Text = 'Processing status';
            app.ProcessingstatusLamp = uilamp(app.UIFigure);
            app.Multilayer_CannyPanel = uipanel(app.UIFigure);
            app.Multilayer_CannyPanel.Title = 'Multilayer_Canny';
            app.MultilayerCannyCheckBox = uicheckbox(app.Multilayer_CannyPanel);
            app.MultilayerCannyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultilayerCannyCheckBoxValueChanged, true);
            app.MultilayerCannyCheckBox.Text = 'Multilayer Canny';
            app.StartlevelSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.StartlevelSpinnerLabel.Text = 'Start level';
            app.StartlevelSpinner = uispinner(app.Multilayer_CannyPanel);
            app.StartlevelSpinner.Limits = [1 15];
            app.StartlevelSpinner.ValueChangedFcn = createCallbackFcn(app, @StartlevelSpinnerValueChanged, true);
            app.StartlevelSpinner.Value = 1;
            app.EndlevelSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.EndlevelSpinnerLabel.Text = 'End level';
            app.EndlevelSpinner = uispinner(app.Multilayer_CannyPanel);
            app.EndlevelSpinner.Limits = [1 15];
            app.EndlevelSpinner.ValueChangedFcn = createCallbackFcn(app, @EndlevelSpinnerValueChanged, true);
            app.EndlevelSpinner.Value = 7;
            app.WeightratioSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.WeightratioSpinnerLabel.Text = 'Weight ratio';
            app.WeightratioSpinner = uispinner(app.Multilayer_CannyPanel);
            app.WeightratioSpinner.Step = 0.1;
            app.WeightratioSpinner.Limits = [0 10];
            app.WeightratioSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightratioSpinnerValueChanged, true);
            app.WeightratioSpinner.Value = 1;
            app.SaveButton = uibutton(app.Multilayer_CannyPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReducetoEdges_Synthetic_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end