classdef ReducetoEdges_knee_study_App4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        framesSpinnerLabel         matlab.ui.control.Label
        framesSpinner              matlab.ui.control.Spinner
        ImageCheckBox              matlab.ui.control.CheckBox
        ProcessingstatusLampLabel  matlab.ui.control.Label
        ProcessingstatusLamp       matlab.ui.control.Lamp
        CropboxCheckBox            matlab.ui.control.CheckBox
        FolderDropDown             matlab.ui.control.DropDown
        FolderDropDownLabel        matlab.ui.control.Label
        FilesDropDown              matlab.ui.control.DropDown
        FilesDropDownLabel         matlab.ui.control.Label
        CannyGradientsPanel        matlab.ui.container.Panel
        FilterDropDownLabel        matlab.ui.control.Label
        FilterDropDown             matlab.ui.control.DropDown
        StepSliderLabel            matlab.ui.control.Label
        StepSlider                 matlab.ui.control.Slider
        threshSpinnerLabel         matlab.ui.control.Label
        threshSpinner              matlab.ui.control.Spinner
        PSpinnerLabel              matlab.ui.control.Label
        PSpinner                   matlab.ui.control.Spinner
        GradientCannyCheckBox      matlab.ui.control.CheckBox
        CannyCheckBox              matlab.ui.control.CheckBox
        GradientICheckBox          matlab.ui.control.CheckBox
        LevelSpinnerLabel          matlab.ui.control.Label
        LevelSpinner               matlab.ui.control.Spinner
        Multilayer_CannyPanel      matlab.ui.container.Panel
        MultilayerCannyCheckBox    matlab.ui.control.CheckBox
        StartlevelSpinnerLabel     matlab.ui.control.Label
        StartlevelSpinner          matlab.ui.control.Spinner
        EndlevelSpinnerLabel       matlab.ui.control.Label
        EndlevelSpinner            matlab.ui.control.Spinner
        WeightratioSpinnerLabel    matlab.ui.control.Label
        WeightratioSpinner         matlab.ui.control.Spinner
        SaveButton                 matlab.ui.control.Button
        OnlynoiseCheckBox          matlab.ui.control.CheckBox
        PSpinner_2Label            matlab.ui.control.Label
        PSpinner_2                 matlab.ui.control.Spinner
        TilesCheckBox              matlab.ui.control.CheckBox
        SetsSpinnerLabel           matlab.ui.control.Label
        SetsSpinner                matlab.ui.control.Spinner
        TypeDropDown               matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        OnlyImageSpinner           matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        filter;
        frames
        frame
        Canny   % Canny parameters
        Sobel   % Sobel parameters
        GradientI%Gradient parameters
        circleMask
        folder
        files
        poses
        markers
        frameNum
        key
        rectROI
        rectPositions
        Tform
        multiLayerCannyArgs
        tiledPoses
        tiledcirleMasks
        posesPerImage
        posesInaTile
        borderMask
        MLC
        Mask
        noiseTray
        syntheticfiles
    end
    
    methods (Access = private)


        function  show(app)
            
            app.ProcessingstatusLamp.Color='red';
            
            if strcmp( app.TypeDropDown.Value, 'Synthetic')
                borderMask=double(imcomplement( app.borderMask>0));
                randomidx=randi([1 size(app.noiseTray,1)],app.posesPerImage,1);
                noise_array=app.noiseTray(randomidx,:,:);
                frame=app.frames{app.frameNum}(:,:,app.SetsSpinner.Value);
                
                Edgeimage=CreateMLC_highLevelFunction(double(frame),borderMask,app.MLC,noise_array);
                
            end
            
            if app.TilesCheckBox.Value && strcmp( app.TypeDropDown.Value, 'Real')
                frame=TileImages(app,app.tiledPoses);
                circleMask=TileImages(app,app.tiledcirleMasks);
                circleMask=circleMask+app.borderMask;
                
            elseif strcmp( app.TypeDropDown.Value, 'Real')
                
                
                frame=imwarp(app.frame,app.Tform(app.frameNum),'OutputView',...
                    imref2d(size(app.frame)),'FillValues', 0);
                circleMask=imwarp(app.circleMask,app.Tform(app.frameNum),'OutputView',...
                    imref2d(size(app.circleMask)),'FillValues', 0);
                
                if app.CropboxCheckBox.Value
                    frame=imcrop(frame,app.rectPositions.(app.files)(app.frameNum,:));
                    frame=imresize(frame,[256,256]);
                    circleMask=imcrop(circleMask,app.rectPositions.(app.files)(app.frameNum,:));
                    circleMask=imresize(circleMask,[256,256]);
                end
                
            end
            R_Canny=uint8(zeros(size(frame,1),size(frame,2),3));
            B_Sobel=R_Canny;
            G_GradientI=R_Canny;
            if strcmp( app.TypeDropDown.Value, 'Real')
                if app.MultilayerCannyCheckBox.Value
                    Edgeimage=MultiLayerCanny(frame,app.MLC);
                    Edgeimage=im2uint8(Edgeimage);
                else
                    if strcmp(app.filter,'Canny') ||app.CannyCheckBox.Value || app.GradientCannyCheckBox.Value
                        BW_Canny=ReduceToEdges5(frame,app.Canny,0);
                        R_Canny(:,:,1)=uint8(BW_Canny)*255;%RGB_Canny(:,:,1);      %Retain only Red
                    end
                    
                    if strcmp(app.filter,'GradientI') || app.GradientICheckBox.Value ||app.GradientCannyCheckBox.Value
                        gradImage=ReduceToEdges5(frame,app.GradientI,0);
                        B=imcomplement(circleMask>0);
                        gradImage=mat2gray(gradImage.*B);
                        G_GradientI(:,:,2)=(gradImage*255);
                    end
                    
                    if app.GradientCannyCheckBox.Value
                        Edgeimage=SubtractGradientCanny(R_Canny(:,:,1),G_GradientI(:,:,2),circleMask);
                    else
                        Edgeimage=R_Canny+B_Sobel+G_GradientI;
                        
                    end
                end
                
                Edgeimage=Edgeimage-(im2uint8(circleMask));
            end
            if app.OnlynoiseCheckBox.Value
                BW=Edgeimage>0;
                BW=BW-bwareaopen(BW,app.PSpinner_2.Value);
                Edgeimage=Edgeimage.*(im2uint8(BW)/255);
            end
            
            Edgeimage=imresize(Edgeimage,1/app.LevelSpinner.Value);
            
            if strcmp( app.TypeDropDown.Value, 'Synthetic')
                %                 frame=(app.frames{app.frameNum}(:,:,app.SetsSpinner.Value));
                %
                %                 randomidx=randi([1 size(app.noiseTray,1)],app.posesPerImage,1);
                %                 noise_array=app.noiseTray(randomidx,:,:);
                %                 tiledNoise=Create_tile_for_Noise(noise_array);
                %
                %                 BW=(app.borderMask+frame)>0;
                %                 noiseMask=im2uint8(imcomplement(BW))/255;
                %
                %
                %                 Edgeimage=Edgeimage+tiledNoise.*noiseMask;
                %
            end
            
                       
            if strcmp( app.TypeDropDown.Value, 'Synthetic')
                figure(2);
                ax=gca;
            else
                figure(1);
                ax=gca;
            end 
                      
            if app.ImageCheckBox.Value
                
                if app.OnlyImageSpinner.Value
                    imshowpair(Edgeimage,imadjust(frame),'blend','Parent',ax);
                else
                    %A=imcomplement(frame(1:256,512:512+256));
                    imshow(imadjust((frame)),'Parent',ax);
                end
            else
                %A=Edgeimage(:,:,1)>0;
                imshow(imcomplement(Edgeimage),'Parent',ax);
                
            end


            app.ProcessingstatusLamp.Color='green';
        end
        
        function UpdateFrames(app)
            X = squeeze(dicomread(app.files));
            app.frames=squeeze(num2cell(X,[1,2]));
            app.folder=str2num(app.FolderDropDown.Value);
            
            LoadNecessaryFiles(app)
            %             for i=1:size(app.frames,1)
            %                 app.frames{i}=im2uint8(medfilt2(app.frames{i}));
            %                 %app.frames{i}=app.frames{i}+app.circleMask;
            %             end
            
            app.framesSpinner.Limits=[1,size(app.frames,1)];
            app.frameNum=app.framesSpinner.Value;
            
            app.frame=app.frames{app.frameNum};
            
            app.filter='GradientI';
            app.PSpinner.Value=app.(app.filter).P;
            app.threshSpinner.Value=app.(app.filter).thresh;
            app.LevelSpinner.Value=1;%round(app.(app.filter).sigma2);
            
            [app.posesInaTile,N]=FindPosesInaTile(app.folder);
            
        end
        
        function LoadNecessaryFiles(app)
            
            if exist("markers.mat",'file')
                load markers.mat
                
                if ~isfield(markers,app.files)
                    markerDefaultPos=[512,512,512,400,512,300,512,200,512,100,512,600];
                    markers.(app.files)= repmat(markerDefaultPos,size(app.frames,1),1);
                end
                
                M=markers.(app.files);
                totalFrames=size(app.frames,1);
                if app.folder==1
                    for i=1:size(M,2)
                        M(:,i)=smooth(M(:,i));
                    end
                end
                app.Tform=FindTform2( M(1:totalFrames,[1:4]));
            end
            
            if exist("rectPositions.mat",'file')
                load rectPositions.mat
                app.rectPositions=rectPositions;
            end
            
            
            if exist("circleMask.mat",'file')
                load circleMask.mat
                app.circleMask=circleMask;
            end
            
            if exist("processedImages.mat",'file')
                load processedImages.mat
                app.tiledPoses=tiledPoses;
                app.tiledcirleMasks=tiledcirleMasks;
            end
            
            load edgesParams2.mat
            C=Canny;
            S=Sobel;
            if ~exist('GradienI')
                GradienI=Canny;
                GradienI.name='GradientI';
            end
            G=GradienI;
            clear Canny Sobel GradienI
            
            if exist("edgesParams3.mat",'file')
                load edgesParams3.mat
            else
                
                Canny.Rotation=C;
                Sobel.Rotation=S;
                GradientI.Rotation=G;
                save ('edgesParams3', 'Canny','Sobel','GradientI');
            end
            
            if ~isfield(Canny,app.files)
                C.thresh=0.001;
                G.P=100;
                G.thresh=0.1;
                
                Canny.(app.files)=C;
                Sobel.(app.files)=S;
                GradientI.(app.files)=G;
                
            end
            
            app.Canny=Canny.(app.files);
            app.Sobel=Sobel.(app.files);
            app.GradientI=GradientI.(app.files);
            
            if exist("multiLayerCannyparams.mat",'file')
                load multiLayerCannyparams.mat
            else
                MLC.start=1;
                MLC.end=6;
                MLC.weight=3;
                MLC.P=300;
                save ('multiLayerCannyparams', 'MLC');
            end
            
            app.MLC=MLC;
            app.StartlevelSpinner.Value=app.MLC.start;
            app.EndlevelSpinner.Value=app.MLC.end;
            app.WeightratioSpinner.Value=app.MLC.weight;
            app.PSpinner_2.Value=MLC.P;
            
            
        end
        
        
        function [tiledImage]=TileImages(app,rawImages)
            rows=sqrt(app.posesPerImage);
            cols=rows;
            r=256;  %TileSize
            tileIndices=reshape(1:app.posesPerImage,rows,cols);
            tileIndices=tileIndices';
            
            rowImages=[];
            tiledImage=[];
            frameNumberMatrix=[];
            frame=im2uint16(zeros(r,r));
            
            for row=1:rows
                for col=1:cols
                    tileIndex=tileIndices(row,col);
                    frames=rawImages{tileIndex};
                    poseIndex=min(app.SetsSpinner.Value,size(frames,3));
                    
                    
                    if isempty(app.posesInaTile{tileIndex})
                        frameNumberMatrix=[frameNumberMatrix,0];
                        frame=frame*0;
                    else
                        frameNumberMatrix=[frameNumberMatrix,app.posesInaTile{tileIndex}(poseIndex)];
                        frame=frames(:,:,poseIndex);
                    end                   
                    
                    
                    rowImages=[rowImages,frame];
                end
                tiledImage=[tiledImage;rowImages];
                rowImages=[];
                
            end
            frameNumberMatrix=reshape(frameNumberMatrix,rows,cols)'
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(1);
            
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            dinfo = dir();
            dinfo(ismember( {dinfo.name}, {'.', '..','Index.xlsx'})) = [];
            
            %Read all the folders in dir and update
            for i=1:size(dinfo,1)
                app.FolderDropDown.Items{i}=dinfo(i).name;
            end
            
            
            %Update dir
            cd(app.FolderDropDown.Value)
            
            %Read all the files in the folders and update
            files = dir(fullfile(cd, '*.DCM'));
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            app.poses = readtable('Poses');
            app.posesPerImage=16;
            
            app.borderMask=im2uint16(CreateAllBorders(app.posesPerImage,15));
            
            load NoiseCollection
            app.noiseTray=[];
            for i=1:size(NoiseCollection,2)
                app.noiseTray=cat(1, app.noiseTray,NoiseCollection{1,i});
            end
            app.noiseTray=double( app.noiseTray);
            UpdateFrames(app)
            
            show(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MLC=app.MLC;
            save ('multiLayerCannyparams', 'MLC');
            
            NoiseCollection=[];
            if exist("NoiseCollection.mat",'file')
                load NoiseCollection.mat
            end
            
            
            frameCount=1;
            for tile=1:size(app.tiledPoses,2)
                
                if ~isempty(app.tiledPoses{tile})
                    for pose =1:size(app.tiledPoses{tile},3)
                        frame=app.tiledPoses{tile}(:,:,pose);
                        circleMask=app.tiledcirleMasks{tile}(:,:,pose);
                        
                        Edgeimage=MultiLayerCanny(frame,app.MLC);
                        Edgeimage=im2uint8(Edgeimage);
                        Edgeimage=Edgeimage-(im2uint8(circleMask));
                        
                        %Collect Noise
                        BW=Edgeimage>0;
                        BW=BW-bwareaopen(BW,app.PSpinner_2.Value);
                        %Edgeimage=Edgeimage.*im2uint8(BW);
                        noiseImage=Edgeimage.*(im2uint8(BW)/255);
                        
                        NoiseCollection{app.folder}(frameCount,:,:)=noiseImage;
                        frameCount=frameCount+1;
                        
                    end
                end
            end
            
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            save ('NoiseCollection', 'NoiseCollection');
            
            cd(app.FolderDropDown.Value);


        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.filter=app.FilterDropDown.Value;
            app.PSpinner.Value=app.(app.filter).P;
            app.threshSpinner.Value=app.(app.filter).thresh;
            app.LevelSpinner.Value=round(app.(app.filter).sigma2);
            
            show(app)
        end

        % Value changed function: framesSpinner
        function framesSpinnerValueChanged(app, event)
            app.frameNum = app.framesSpinner.Value;
            app.frame=app.frames{app.frameNum};
            show(app)
        end

        % Value changed function: ImageCheckBox
        function ImageCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: threshSpinner
        function threshSpinnerValueChanged(app, event)
            app.(app.filter).thresh = app.threshSpinner.Value;
            show(app)
        end

        % Value changed function: StepSlider
        function StepSliderValueChanged(app, event)
            app.threshSpinner.Step=10^(app.StepSlider.Value);
        end

        % Value changed function: LevelSpinner
        function LevelSpinnerValueChanged(app, event)
            app.(app.filter).sigma2 = app.LevelSpinner.Value;
            show(app)
        end

        % Value changed function: PSpinner
        function PSpinnerValueChanged(app, event)
            app.(app.filter).P = ceil(app.PSpinner.Value);
            show(app);
        end

        % Value changed function: GradientCannyCheckBox
        function GradientCannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
            app.TabGroup.SelectedTab.Title
        end

        % Value changed function: CannyCheckBox
        function CannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: GradientICheckBox
        function GradientICheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: FolderDropDown
        function FolderDropDownValueChanged(app, event)
            %UpdatePostion(app)
            cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
            
            cd(app.FolderDropDown.Value)
            
            files = dir(fullfile(cd, '*.DCM'));
            app.FilesDropDown.Items={};
            for i=1:size(files,1)
                fileName=strrep(files(i).name,'.dcm','');
                app.FilesDropDown.Items{i}=strrep(fileName,'.DCM','');%Removes the dot
            end
            app.FilesDropDown.Value='Rotation';
            app.files=app.FilesDropDown.Value;
            
            UpdateFrames(app)
            
            show(app)
        end

        % Value changed function: FilesDropDown
        function FilesDropDownValueChanged(app, event)
            app.files=app.FilesDropDown.Value;
            UpdateFrames(app);
            show(app);
        end

        % Value changed function: CropboxCheckBox
        function CropboxCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: MultilayerCannyCheckBox
        function MultilayerCannyCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: StartlevelSpinner
        function StartlevelSpinnerValueChanged(app, event)
            app.MLC.start = app.StartlevelSpinner.Value;
            show(app);
        end

        % Value changed function: EndlevelSpinner
        function EndlevelSpinnerValueChanged(app, event)
            app.MLC.end= app.EndlevelSpinner.Value;
            show(app);
        end

        % Value changed function: WeightratioSpinner
        function WeightratioSpinnerValueChanged(app, event)
            app.MLC.weight = app.WeightratioSpinner.Value;
            show(app);
        end

        % Value changed function: TilesCheckBox
        function TilesCheckBoxValueChanged(app, event)
            show(app);
        end

        % Value changed function: SetsSpinner
        function SetsSpinnerValueChanged(app, event)
            show(app);
            
        end

        % Value changed function: OnlynoiseCheckBox
        function OnlynoiseCheckBoxValueChanged(app, event)
            show(app);
            
        end

        % Value changed function: PSpinner_2
        function PSpinner_2ValueChanged(app, event)
            show(app);
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'Real')
                app.FolderDropDown.Enable=true;
                app.FilesDropDown.Enable=true;
                app.SetsSpinner.Limits=[1,20];
                app.SetsSpinner.Value=1;
                
                cd 'D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Datasets\Fluoroscopy Knee brace Study\Knee brace study\Stage3_Subjects';
                cd(app.FolderDropDown.Value)
                UpdateFrames(app);
            end
            
            if strcmp(app.TypeDropDown.Value,'Synthetic')
                app.FolderDropDown.Enable=false;
                app.FilesDropDown.Enable=false;
                app.SetsSpinner.Limits=[1,3];
                app.SetsSpinner.Value=1;
                
                
                inputPath='D:\OneDrive - University of Waterloo\Thesis\Projects\IC\Python\ML1\Code\Synthetic Images\1';
                cd(inputPath)
                inputDir=dir(inputPath);
                inputDir(ismember( {inputDir.name}, {'.', '..','Index.xlsx'})) = [];
                
                files = dir(fullfile(cd, '*.png'));
                [~,T_idx]=natsortfiles({files.name});  %Name sorting
                files=files(T_idx);
                
                %                 app.syntheticfiles
                multiplicationFactor = 10;
                app.frames=[];
                for i=1:size(files,1)
                    inputImage=imread(files(i).name);
                    X=inputImage/multiplicationFactor;
                    app.frames{i}=X(:,1:1024,:);
                end
            end
            show(app);
        end

        % Value changed function: OnlyImageSpinner
        function OnlyImageSpinnerValueChanged(app, event)
            show(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.framesSpinnerLabel = uilabel(app.UIFigure);
            app.framesSpinnerLabel.Text = 'frames';
            app.framesSpinner = uispinner(app.UIFigure);
            app.framesSpinner.Limits = [1 100];
            app.framesSpinner.ValueChangedFcn = createCallbackFcn(app, @framesSpinnerValueChanged, true);
            app.framesSpinner.Value = 1;
            app.ImageCheckBox = uicheckbox(app.UIFigure);
            app.ImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageCheckBoxValueChanged, true);
            app.ImageCheckBox.Text = 'Image';
            app.ProcessingstatusLampLabel = uilabel(app.UIFigure);
            app.ProcessingstatusLampLabel.Text = 'Processing status';
            app.ProcessingstatusLamp = uilamp(app.UIFigure);
            app.CropboxCheckBox = uicheckbox(app.UIFigure);
            app.CropboxCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropboxCheckBoxValueChanged, true);
            app.CropboxCheckBox.Text = 'Crop box';
            app.FolderDropDown = uidropdown(app.UIFigure);
            app.FolderDropDown.Items = {};
            app.FolderDropDown.ValueChangedFcn = createCallbackFcn(app, @FolderDropDownValueChanged, true);
            app.FolderDropDown.Value = {};
            app.FolderDropDownLabel = uilabel(app.UIFigure);
            app.FolderDropDownLabel.Text = 'Folder';
            app.FilesDropDown = uidropdown(app.UIFigure);
            app.FilesDropDown.Items = {};
            app.FilesDropDown.ValueChangedFcn = createCallbackFcn(app, @FilesDropDownValueChanged, true);
            app.FilesDropDown.Value = {};
            app.FilesDropDownLabel = uilabel(app.UIFigure);
            app.FilesDropDownLabel.Text = 'Files';
            app.CannyGradientsPanel = uipanel(app.UIFigure);
            app.CannyGradientsPanel.Title = 'Canny/Gradients';
            app.FilterDropDownLabel = uilabel(app.CannyGradientsPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.CannyGradientsPanel);
            app.FilterDropDown.Items = {'Canny', 'GradientI'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'GradientI';
            app.StepSliderLabel = uilabel(app.CannyGradientsPanel);
            app.StepSliderLabel.Text = 'Step';
            app.StepSlider = uislider(app.CannyGradientsPanel);
            app.StepSlider.Limits = [-8 0];
            app.StepSlider.MajorTicks = [-8 -7 -6 -5 -4 -3 -2 -1 0];
            app.StepSlider.ValueChangedFcn = createCallbackFcn(app, @StepSliderValueChanged, true);
            app.StepSlider.MinorTicks = [];
            app.StepSlider.Value = -3;
            app.threshSpinnerLabel = uilabel(app.CannyGradientsPanel);
            app.threshSpinnerLabel.Text = 'thresh';
            app.threshSpinner = uispinner(app.CannyGradientsPanel);
            app.threshSpinner.Step = 0.1;
            app.threshSpinner.Limits = [0 1];
            app.threshSpinner.ValueChangedFcn = createCallbackFcn(app, @threshSpinnerValueChanged, true);
            app.threshSpinner.Value = 0.1;
            app.PSpinnerLabel = uilabel(app.CannyGradientsPanel);
            app.PSpinnerLabel.Text = 'P';
            app.PSpinner = uispinner(app.CannyGradientsPanel);
            app.PSpinner.Step = 10;
            app.PSpinner.Limits = [0 10000];
            app.PSpinner.ValueChangedFcn = createCallbackFcn(app, @PSpinnerValueChanged, true);
            app.GradientCannyCheckBox = uicheckbox(app.CannyGradientsPanel);
            app.GradientCannyCheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientCannyCheckBoxValueChanged, true);
            app.GradientCannyCheckBox.Text = 'Gradient-Canny';
            app.CannyCheckBox = uicheckbox(app.CannyGradientsPanel);
            app.CannyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CannyCheckBoxValueChanged, true);
            app.CannyCheckBox.Text = 'Canny';
            app.GradientICheckBox = uicheckbox(app.CannyGradientsPanel);
            app.GradientICheckBox.ValueChangedFcn = createCallbackFcn(app, @GradientICheckBoxValueChanged, true);
            app.GradientICheckBox.Text = 'GradientI';
            app.LevelSpinnerLabel = uilabel(app.CannyGradientsPanel);
            app.LevelSpinnerLabel.Text = 'Level';
            app.LevelSpinner = uispinner(app.CannyGradientsPanel);
            app.LevelSpinner.Limits = [1 15];
            app.LevelSpinner.ValueChangedFcn = createCallbackFcn(app, @LevelSpinnerValueChanged, true);
            app.LevelSpinner.Value = 1;
            app.Multilayer_CannyPanel = uipanel(app.UIFigure);
            app.Multilayer_CannyPanel.Title = 'Multilayer_Canny';
            app.MultilayerCannyCheckBox = uicheckbox(app.Multilayer_CannyPanel);
            app.MultilayerCannyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultilayerCannyCheckBoxValueChanged, true);
            app.MultilayerCannyCheckBox.Text = 'Multilayer Canny';
            app.StartlevelSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.StartlevelSpinnerLabel.Text = 'Start level';
            app.StartlevelSpinner = uispinner(app.Multilayer_CannyPanel);
            app.StartlevelSpinner.Limits = [1 15];
            app.StartlevelSpinner.ValueChangedFcn = createCallbackFcn(app, @StartlevelSpinnerValueChanged, true);
            app.StartlevelSpinner.Value = 1;
            app.EndlevelSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.EndlevelSpinnerLabel.Text = 'End level';
            app.EndlevelSpinner = uispinner(app.Multilayer_CannyPanel);
            app.EndlevelSpinner.Limits = [1 15];
            app.EndlevelSpinner.ValueChangedFcn = createCallbackFcn(app, @EndlevelSpinnerValueChanged, true);
            app.EndlevelSpinner.Value = 7;
            app.WeightratioSpinnerLabel = uilabel(app.Multilayer_CannyPanel);
            app.WeightratioSpinnerLabel.Text = 'Weight ratio';
            app.WeightratioSpinner = uispinner(app.Multilayer_CannyPanel);
            app.WeightratioSpinner.Step = 0.1;
            app.WeightratioSpinner.Limits = [0 10];
            app.WeightratioSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightratioSpinnerValueChanged, true);
            app.WeightratioSpinner.Value = 1;
            app.SaveButton = uibutton(app.Multilayer_CannyPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OnlynoiseCheckBox = uicheckbox(app.Multilayer_CannyPanel);
            app.OnlynoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlynoiseCheckBoxValueChanged, true);
            app.OnlynoiseCheckBox.Text = 'Only noise';
            app.PSpinner_2Label = uilabel(app.Multilayer_CannyPanel);
            app.PSpinner_2Label.Text = 'P';
            app.PSpinner_2 = uispinner(app.Multilayer_CannyPanel);
            app.PSpinner_2.Step = 10;
            app.PSpinner_2.Limits = [0 10000];
            app.PSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PSpinner_2ValueChanged, true);
            app.TilesCheckBox = uicheckbox(app.UIFigure);
            app.TilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TilesCheckBoxValueChanged, true);
            app.TilesCheckBox.Text = 'Tiles';
            app.SetsSpinnerLabel = uilabel(app.UIFigure);
            app.SetsSpinnerLabel.Text = 'Sets';
            app.SetsSpinner = uispinner(app.UIFigure);
            app.SetsSpinner.Limits = [1 20];
            app.SetsSpinner.ValueChangedFcn = createCallbackFcn(app, @SetsSpinnerValueChanged, true);
            app.SetsSpinner.Value = 1;
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Real', 'Synthetic'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Real';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.OnlyImageSpinner = uispinner(app.UIFigure);
            app.OnlyImageSpinner.Limits = [0 1];
            app.OnlyImageSpinner.ValueChangedFcn = createCallbackFcn(app, @OnlyImageSpinnerValueChanged, true);
            app.OnlyImageSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReducetoEdges_knee_study_App4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end